classdef NA22_Marx_app_3cell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Cell1Panel                 matlab.ui.container.Panel
        TriggerDisableCheckBox     matlab.ui.control.CheckBox
        ChargeDisableCheckBox      matlab.ui.control.CheckBox
        Coil1EnableCheckBox        matlab.ui.control.CheckBox
        Coil2EnableCheckBox        matlab.ui.control.CheckBox
        BatteryuCEnableCheckBox    matlab.ui.control.CheckBox
        ChargeinprogressLampLabel  matlab.ui.control.Label
        ChargeinprogressLamp       matlab.ui.control.Lamp
        ChargecompleteLampLabel    matlab.ui.control.Label
        ChargecompleteLamp         matlab.ui.control.Lamp
        VLabel_10                  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        CountsLabel                matlab.ui.control.Label
        HVSetEditFieldLabel        matlab.ui.control.Label
        HVSetEditField             matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        CountsLabel_2              matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        CountsLabel_3              matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        CountsLabel_4              matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        VLabel_2                   matlab.ui.control.Label
        VLabel_3                   matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        HVActLabel                 matlab.ui.control.Label
        VActLabel                  matlab.ui.control.Label
        TS3Label                   matlab.ui.control.Label
        Cell2Panel                 matlab.ui.container.Panel
        TriggerDisableCheckBox_2   matlab.ui.control.CheckBox
        ChargeDisableCheckBox_2    matlab.ui.control.CheckBox
        Coil1EnableCheckBox_2      matlab.ui.control.CheckBox
        Coil2EnableCheckBox_2      matlab.ui.control.CheckBox
        BatteryuCEnableCheckBox_2  matlab.ui.control.CheckBox
        Stat1Lamp_2Label           matlab.ui.control.Label
        Stat1Lamp_2                matlab.ui.control.Lamp
        Stat2Lamp_2Label           matlab.ui.control.Label
        Stat2Lamp_2                matlab.ui.control.Lamp
        VLabel_4                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        CountsLabel_5              matlab.ui.control.Label
        HVSetEditField_2Label      matlab.ui.control.Label
        HVSetEditField_2           matlab.ui.control.NumericEditField
        Label_10                   matlab.ui.control.Label
        CountsLabel_6              matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        CountsLabel_7              matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        CountsLabel_8              matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        VLabel_5                   matlab.ui.control.Label
        VLabel_6                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        HVActLabel_2               matlab.ui.control.Label
        VActLabel_2                matlab.ui.control.Label
        TS3Label_2                 matlab.ui.control.Label
        Cell3Panel                 matlab.ui.container.Panel
        TriggerDisableCheckBox_3   matlab.ui.control.CheckBox
        ChargeDisableCheckBox_3    matlab.ui.control.CheckBox
        Coil1EnableCheckBox_3      matlab.ui.control.CheckBox
        Coil2EnableCheckBox_3      matlab.ui.control.CheckBox
        BatteryuCEnableCheckBox_3  matlab.ui.control.CheckBox
        Stat1Lamp_3Label           matlab.ui.control.Label
        Stat1Lamp_3                matlab.ui.control.Lamp
        Stat2Lamp_3Label           matlab.ui.control.Label
        Stat2Lamp_3                matlab.ui.control.Lamp
        VLabel_7                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        CountsLabel_9              matlab.ui.control.Label
        HVSetEditField_3Label      matlab.ui.control.Label
        HVSetEditField_3           matlab.ui.control.NumericEditField
        Label_18                   matlab.ui.control.Label
        CountsLabel_10             matlab.ui.control.Label
        Label_19                   matlab.ui.control.Label
        CountsLabel_11             matlab.ui.control.Label
        Label_20                   matlab.ui.control.Label
        CountsLabel_12             matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        Label_22                   matlab.ui.control.Label
        Label_23                   matlab.ui.control.Label
        VLabel_8                   matlab.ui.control.Label
        VLabel_9                   matlab.ui.control.Label
        Label_24                   matlab.ui.control.Label
        HVActLabel_3               matlab.ui.control.Label
        VActLabel_3                matlab.ui.control.Label
        TS3Label_3                 matlab.ui.control.Label
        RefreshButton              matlab.ui.control.Button
        COMPortsDropDownLabel      matlab.ui.control.Label
        COMPortsDropDown           matlab.ui.control.DropDown
        RefreshCOMButton           matlab.ui.control.Button
        ConnectSerialButton        matlab.ui.control.Button
        DisconnectSerialButton     matlab.ui.control.Button
        Cell1CheckBox              matlab.ui.control.CheckBox
        Cell2CheckBox              matlab.ui.control.CheckBox
        Cell3CheckBox              matlab.ui.control.CheckBox
        CellsActiveLabel           matlab.ui.control.Label
        StartHeartButton           matlab.ui.control.Button
        StopHeartButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        MyTimer = 0;
    end
    
    methods (Access = private)
        
        function TimerCallback(app, obj, event, string_arg)
            RefreshButtonPushed(app, event);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            ttyList={};
            portOrder =[];
            [stat, result] = system('mode');
            % identify the COM ports in the output string and format in cell array
            portList = regexp(result,'COM\d+:','match');
            k=0;
            for p = portList
                k=k+1;
                portNum = regexp(p{1},'\d+','match');
                portName = regexp(p{1},'COM\d+','match');
                ttyList = [ttyList; {portNum{1}, portName{1}}];
                portOrder = [portOrder str2num(portNum{1})];
            end
            % return the list sorted with largest number first
            [p2,i]=sort(portOrder,2,'descend');
            a = ttyList(i,:);
            k=size(a);
            if k(1)>0
                for n=1:k(1)
                    a(n,2);
                end
                app.COMPortsDropDown.Items=a(:,2);
            else
                app.COMPortsDropDown.Items='None';
            end
        end

        % Button pushed function: ConnectSerialButton
        function ConnectSerialButtonPushed(app, event)
            global s;
            try
                s = serialport(app.COMPortsDropDown.Value,38400);
                configureTerminator(s,'LF'); %set comma as terminator (44)
            catch 
            end
            pause(1);
        end

        % Button pushed function: DisconnectSerialButton
        function DisconnectSerialButtonPushed(app, event)
            clear global;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            global s;
            
            %ADD LOOP OVER ALL CELLS
            
            for n=1:2 %double tap
                control = 0b00000000;
                if (app.TriggerDisableCheckBox.Value)
                    control=bitset(control,1); %bit 0 in arduino, 1 in matlab
                end
                if (app.ChargeDisableCheckBox.Value)
                    control=bitset(control,2); %bit 1 in arduino, 2 in matlab
                end
                if (app.Coil1EnableCheckBox.Value)
                    control=bitset(control,3); %bit 2 in arduino, 3 in matlab
                end
                if (app.Coil2EnableCheckBox.Value)
                    control=bitset(control,4); %bit 3 in arduino, 4 in matlab
                end
                if (app.BatteryuCEnableCheckBox.Value)
                    control=bitset(control,5); %bit 4 in arduino, 5 in matlab
                end
                write(s,['!C 000 ' num2str(control,'%03d') ' ' num2str(control,'%03d') ' ' num2str(control,'%03d') ','],"char");                  %end byte
                echo =      char(readline(s));
                
                %%Here read in the two states and update the LEDs
                parse = strsplit(echo);
                num_cell = numel(parse)-3;
                control=sprintf('%08d',str2num(dec2bin(str2num(parse{3}(1:3)))));
                if(strcmp(control(3),'1')) %stat1
                    app.ChargeinprogressLamp.Color = [0.94,0.94,0.94];  %grey if 1
                else
                    app.ChargeinprogressLamp.Color = [0,1,0];  %green if 0
                end
                
                if(strcmp(control(2),'1')) %stat2
                    app.ChargecompleteLamp.Color = [0.94,0.94,0.94];  %grey if 1
                else
                    app.ChargecompleteLamp.Color = [0,1,0];  %green if 0
                end
                
                
                %% Convert Vdes from Volts to counts
                x=app.HVSetEditField.Value;

                cnts=round(-6.04341e-11*x^5+1.58494E-07*x^4 +-0.00016449*x^3 + 0.085786488*x^2 +-23.51670929*x + 2987.713154);
                if cnts>1022
                    cnts=1022;
                elseif cnts<10;
                    cnts=10;
                end
                
                app.Label.Text = sprintf('%04d',(cnts));
                write(s,['!H 000 ' num2str(cnts,'%04d') ','],"char");
                echo =      char(readline(s));
                parse = strsplit(echo);
                HV_act=sprintf('%04d',(str2num(parse{3}(1:4)))); %in counts
                app.Label_5.Text = num2str(str2num(HV_act)/.418,'%4.1f');
                app.Label_2.Text = HV_act;
                
                write(s,['!B 000 0000,'],"char");
                echo =      char(readline(s));
                parse = strsplit(echo);
                BV_act=sprintf('%04d',(str2num(parse{3}(1:4)))); %in counts
                app.Label_6.Text = num2str(str2num(BV_act)*5/2^10*6,'%4.1f');
                app.Label_3.Text = BV_act;
                
                write(s,['!T 000 0000,'],"char");
                echo =      char(readline(s));
                parse = strsplit(echo);
                T_act=sprintf('%04d',(str2num(parse{3}(1:4)))); %in counts
                app.Label_7.Text = T_act;
                app.Label_4.Text = T_act;
                
            end


        end

        % Button pushed function: StartHeartButton
        function StartHeartButtonPushed(app, event)
            app.MyTimer = timer('Period',1,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.MyTimer.TimerFcn = @app.TimerCallback; % Your Timer Callback
            start(app.MyTimer);
            app.StartHeartButton.BackgroundColor = [0,1,0];
        end

        % Button pushed function: StopHeartButton
        function StopHeartButtonPushed(app, event)
            stop(app.MyTimer);
            app.StartHeartButton.BackgroundColor = [.96,.96,.96];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Cell1Panel = uipanel(app.UIFigure);
            app.Cell1Panel.Title = 'Cell 1';
            app.TriggerDisableCheckBox = uicheckbox(app.Cell1Panel);
            app.TriggerDisableCheckBox.Text = 'Trigger Disable';
            app.TriggerDisableCheckBox.Value = true;
            app.ChargeDisableCheckBox = uicheckbox(app.Cell1Panel);
            app.ChargeDisableCheckBox.Text = 'Charge Disable';
            app.ChargeDisableCheckBox.Value = true;
            app.Coil1EnableCheckBox = uicheckbox(app.Cell1Panel);
            app.Coil1EnableCheckBox.Text = 'Coil 1 Enable';
            app.Coil2EnableCheckBox = uicheckbox(app.Cell1Panel);
            app.Coil2EnableCheckBox.Text = 'Coil 2 Enable';
            app.BatteryuCEnableCheckBox = uicheckbox(app.Cell1Panel);
            app.BatteryuCEnableCheckBox.Text = 'Battery uC Enable';
            app.ChargeinprogressLampLabel = uilabel(app.Cell1Panel);
            app.ChargeinprogressLampLabel.Text = 'Charge in progress';
            app.ChargeinprogressLamp = uilamp(app.Cell1Panel);
            app.ChargecompleteLampLabel = uilabel(app.Cell1Panel);
            app.ChargecompleteLampLabel.Text = 'Charge complete';
            app.ChargecompleteLamp = uilamp(app.Cell1Panel);
            app.VLabel_10 = uilabel(app.Cell1Panel);
            app.VLabel_10.Text = '(V)';
            app.Label = uilabel(app.Cell1Panel);
            app.Label.Text = '.';
            app.CountsLabel = uilabel(app.Cell1Panel);
            app.CountsLabel.Text = 'Counts';
            app.HVSetEditFieldLabel = uilabel(app.Cell1Panel);
            app.HVSetEditFieldLabel.Text = 'HV Set';
            app.HVSetEditField = uieditfield(app.Cell1Panel, 'numeric');
            app.HVSetEditField.Limits = [135 756];
            app.HVSetEditField.Value = 135;
            app.Label_2 = uilabel(app.Cell1Panel);
            app.Label_2.Text = '.';
            app.CountsLabel_2 = uilabel(app.Cell1Panel);
            app.CountsLabel_2.Text = 'Counts';
            app.Label_3 = uilabel(app.Cell1Panel);
            app.Label_3.Text = '.';
            app.CountsLabel_3 = uilabel(app.Cell1Panel);
            app.CountsLabel_3.Text = 'Counts';
            app.Label_4 = uilabel(app.Cell1Panel);
            app.Label_4.Text = '.';
            app.CountsLabel_4 = uilabel(app.Cell1Panel);
            app.CountsLabel_4.Text = 'Counts';
            app.Label_5 = uilabel(app.Cell1Panel);
            app.Label_5.Text = '.';
            app.Label_6 = uilabel(app.Cell1Panel);
            app.Label_6.Text = '.';
            app.Label_7 = uilabel(app.Cell1Panel);
            app.Label_7.Text = '.';
            app.VLabel_2 = uilabel(app.Cell1Panel);
            app.VLabel_2.Text = '(V)';
            app.VLabel_3 = uilabel(app.Cell1Panel);
            app.VLabel_3.Text = '(V)';
            app.Label_8 = uilabel(app.Cell1Panel);
            app.Label_8.Text = '(?)';
            app.HVActLabel = uilabel(app.Cell1Panel);
            app.HVActLabel.Text = 'HV Act';
            app.VActLabel = uilabel(app.Cell1Panel);
            app.VActLabel.Text = '14V Act';
            app.TS3Label = uilabel(app.Cell1Panel);
            app.TS3Label.Text = 'TS3';
            app.Cell2Panel = uipanel(app.UIFigure);
            app.Cell2Panel.Title = 'Cell 2';
            app.TriggerDisableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.TriggerDisableCheckBox_2.Text = 'Trigger Disable';
            app.TriggerDisableCheckBox_2.Value = true;
            app.ChargeDisableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.ChargeDisableCheckBox_2.Text = 'Charge Disable';
            app.ChargeDisableCheckBox_2.Value = true;
            app.Coil1EnableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.Coil1EnableCheckBox_2.Text = 'Coil 1 Enable';
            app.Coil2EnableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.Coil2EnableCheckBox_2.Text = 'Coil 2 Enable';
            app.BatteryuCEnableCheckBox_2 = uicheckbox(app.Cell2Panel);
            app.BatteryuCEnableCheckBox_2.Text = 'Battery uC Enable';
            app.Stat1Lamp_2Label = uilabel(app.Cell2Panel);
            app.Stat1Lamp_2Label.Text = 'Stat 1';
            app.Stat1Lamp_2 = uilamp(app.Cell2Panel);
            app.Stat2Lamp_2Label = uilabel(app.Cell2Panel);
            app.Stat2Lamp_2Label.Text = 'Stat 2';
            app.Stat2Lamp_2 = uilamp(app.Cell2Panel);
            app.VLabel_4 = uilabel(app.Cell2Panel);
            app.VLabel_4.Text = '(V)';
            app.Label_9 = uilabel(app.Cell2Panel);
            app.Label_9.Text = '.';
            app.CountsLabel_5 = uilabel(app.Cell2Panel);
            app.CountsLabel_5.Text = 'Counts';
            app.HVSetEditField_2Label = uilabel(app.Cell2Panel);
            app.HVSetEditField_2Label.Text = 'HV Set';
            app.HVSetEditField_2 = uieditfield(app.Cell2Panel, 'numeric');
            app.HVSetEditField_2.Limits = [14 800];
            app.HVSetEditField_2.Value = 14;
            app.Label_10 = uilabel(app.Cell2Panel);
            app.Label_10.Text = '.';
            app.CountsLabel_6 = uilabel(app.Cell2Panel);
            app.CountsLabel_6.Text = 'Counts';
            app.Label_11 = uilabel(app.Cell2Panel);
            app.Label_11.Text = '.';
            app.CountsLabel_7 = uilabel(app.Cell2Panel);
            app.CountsLabel_7.Text = 'Counts';
            app.Label_12 = uilabel(app.Cell2Panel);
            app.Label_12.Text = '.';
            app.CountsLabel_8 = uilabel(app.Cell2Panel);
            app.CountsLabel_8.Text = 'Counts';
            app.Label_13 = uilabel(app.Cell2Panel);
            app.Label_13.Text = '.';
            app.Label_14 = uilabel(app.Cell2Panel);
            app.Label_14.Text = '.';
            app.Label_15 = uilabel(app.Cell2Panel);
            app.Label_15.Text = '.';
            app.VLabel_5 = uilabel(app.Cell2Panel);
            app.VLabel_5.Text = '(V)';
            app.VLabel_6 = uilabel(app.Cell2Panel);
            app.VLabel_6.Text = '(V)';
            app.Label_16 = uilabel(app.Cell2Panel);
            app.Label_16.Text = '(?)';
            app.HVActLabel_2 = uilabel(app.Cell2Panel);
            app.HVActLabel_2.Text = 'HV Act';
            app.VActLabel_2 = uilabel(app.Cell2Panel);
            app.VActLabel_2.Text = '14V Act';
            app.TS3Label_2 = uilabel(app.Cell2Panel);
            app.TS3Label_2.Text = 'TS3';
            app.Cell3Panel = uipanel(app.UIFigure);
            app.Cell3Panel.Title = 'Cell 3';
            app.TriggerDisableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.TriggerDisableCheckBox_3.Text = 'Trigger Disable';
            app.TriggerDisableCheckBox_3.Value = true;
            app.ChargeDisableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.ChargeDisableCheckBox_3.Text = 'Charge Disable';
            app.ChargeDisableCheckBox_3.Value = true;
            app.Coil1EnableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.Coil1EnableCheckBox_3.Text = 'Coil 1 Enable';
            app.Coil2EnableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.Coil2EnableCheckBox_3.Text = 'Coil 2 Enable';
            app.BatteryuCEnableCheckBox_3 = uicheckbox(app.Cell3Panel);
            app.BatteryuCEnableCheckBox_3.Text = 'Battery uC Enable';
            app.Stat1Lamp_3Label = uilabel(app.Cell3Panel);
            app.Stat1Lamp_3Label.Text = 'Stat 1';
            app.Stat1Lamp_3 = uilamp(app.Cell3Panel);
            app.Stat2Lamp_3Label = uilabel(app.Cell3Panel);
            app.Stat2Lamp_3Label.Text = 'Stat 2';
            app.Stat2Lamp_3 = uilamp(app.Cell3Panel);
            app.VLabel_7 = uilabel(app.Cell3Panel);
            app.VLabel_7.Text = '(V)';
            app.Label_17 = uilabel(app.Cell3Panel);
            app.Label_17.Text = '.';
            app.CountsLabel_9 = uilabel(app.Cell3Panel);
            app.CountsLabel_9.Text = 'Counts';
            app.HVSetEditField_3Label = uilabel(app.Cell3Panel);
            app.HVSetEditField_3Label.Text = 'HV Set';
            app.HVSetEditField_3 = uieditfield(app.Cell3Panel, 'numeric');
            app.HVSetEditField_3.Limits = [14 800];
            app.HVSetEditField_3.Value = 14;
            app.Label_18 = uilabel(app.Cell3Panel);
            app.Label_18.Text = '.';
            app.CountsLabel_10 = uilabel(app.Cell3Panel);
            app.CountsLabel_10.Text = 'Counts';
            app.Label_19 = uilabel(app.Cell3Panel);
            app.Label_19.Text = '.';
            app.CountsLabel_11 = uilabel(app.Cell3Panel);
            app.CountsLabel_11.Text = 'Counts';
            app.Label_20 = uilabel(app.Cell3Panel);
            app.Label_20.Text = '.';
            app.CountsLabel_12 = uilabel(app.Cell3Panel);
            app.CountsLabel_12.Text = 'Counts';
            app.Label_21 = uilabel(app.Cell3Panel);
            app.Label_21.Text = '.';
            app.Label_22 = uilabel(app.Cell3Panel);
            app.Label_22.Text = '.';
            app.Label_23 = uilabel(app.Cell3Panel);
            app.Label_23.Text = '.';
            app.VLabel_8 = uilabel(app.Cell3Panel);
            app.VLabel_8.Text = '(V)';
            app.VLabel_9 = uilabel(app.Cell3Panel);
            app.VLabel_9.Text = '(V)';
            app.Label_24 = uilabel(app.Cell3Panel);
            app.Label_24.Text = '(?)';
            app.HVActLabel_3 = uilabel(app.Cell3Panel);
            app.HVActLabel_3.Text = 'HV Act';
            app.VActLabel_3 = uilabel(app.Cell3Panel);
            app.VActLabel_3.Text = '14V Act';
            app.TS3Label_3 = uilabel(app.Cell3Panel);
            app.TS3Label_3.Text = 'TS3';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.COMPortsDropDownLabel = uilabel(app.UIFigure);
            app.COMPortsDropDownLabel.Text = 'COM Ports';
            app.COMPortsDropDown = uidropdown(app.UIFigure);
            app.RefreshCOMButton = uibutton(app.UIFigure, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.ConnectSerialButton = uibutton(app.UIFigure, 'push');
            app.ConnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerialButtonPushed, true);
            app.ConnectSerialButton.Text = 'Connect Serial';
            app.DisconnectSerialButton = uibutton(app.UIFigure, 'push');
            app.DisconnectSerialButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSerialButtonPushed, true);
            app.DisconnectSerialButton.Text = 'Disconnect Serial';
            app.Cell1CheckBox = uicheckbox(app.UIFigure);
            app.Cell1CheckBox.Text = 'Cell 1';
            app.Cell1CheckBox.Value = true;
            app.Cell2CheckBox = uicheckbox(app.UIFigure);
            app.Cell2CheckBox.Text = 'Cell 2';
            app.Cell3CheckBox = uicheckbox(app.UIFigure);
            app.Cell3CheckBox.Text = 'Cell 3';
            app.CellsActiveLabel = uilabel(app.UIFigure);
            app.CellsActiveLabel.Text = 'Cells Active';
            app.StartHeartButton = uibutton(app.UIFigure, 'push');
            app.StartHeartButton.ButtonPushedFcn = createCallbackFcn(app, @StartHeartButtonPushed, true);
            app.StartHeartButton.Text = 'Start Heart';
            app.StopHeartButton = uibutton(app.UIFigure, 'push');
            app.StopHeartButton.ButtonPushedFcn = createCallbackFcn(app, @StopHeartButtonPushed, true);
            app.StopHeartButton.Text = 'Stop Heart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NA22_Marx_app_3cell

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end